classdef fproj_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BMIGauge                        matlab.ui.control.SemicircularGauge
        RatiovsAverageCalorieIntakeGauge  matlab.ui.control.LinearGauge
        RatiovsAverageCalorieIntakeGaugeLabel  matlab.ui.control.Label
        RatiovsMinimumCalorieIntakeLabel  matlab.ui.control.Label
        TotalCalorieskcalEditField      matlab.ui.control.NumericEditField
        TotalCalorieskcalEditFieldLabel  matlab.ui.control.Label
        CAreaTextArea                   matlab.ui.control.TextArea
        CCountGauge                     matlab.ui.control.LinearGauge
        CalorieaddEditField             matlab.ui.control.NumericEditField
        CalorieInterpretationEditField  matlab.ui.control.EditField
        CalorieInterpretationEditFieldLabel  matlab.ui.control.Label
        DailyCalorieIntakeforanadultisaround2000caloriesLabel  matlab.ui.control.Label
        Food9DropDown                   matlab.ui.control.DropDown
        Food9DropDownLabel              matlab.ui.control.Label
        Food8DropDown                   matlab.ui.control.DropDown
        Food8DropDownLabel              matlab.ui.control.Label
        Food7DropDown                   matlab.ui.control.DropDown
        Food7DropDownLabel              matlab.ui.control.Label
        Food6DropDown                   matlab.ui.control.DropDown
        Food6DropDownLabel              matlab.ui.control.Label
        Food5DropDown                   matlab.ui.control.DropDown
        Food5DropDownLabel              matlab.ui.control.Label
        Food4DropDown                   matlab.ui.control.DropDown
        Food4DropDownLabel              matlab.ui.control.Label
        Food3DropDown                   matlab.ui.control.DropDown
        Food3DropDownLabel              matlab.ui.control.Label
        Food2DropDown                   matlab.ui.control.DropDown
        Food2DropDownLabel              matlab.ui.control.Label
        Food1DropDown                   matlab.ui.control.DropDown
        Food1DropDownLabel              matlab.ui.control.Label
        DinnerLabel                     matlab.ui.control.Label
        LunchLabel                      matlab.ui.control.Label
        BreakfastLabel                  matlab.ui.control.Label
        WhatdoesyourdailydietcomposeofLabel  matlab.ui.control.Label
        CheckCaloriesButton             matlab.ui.control.Button
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        BodyFatEditField                matlab.ui.control.NumericEditField
        BodyFatEditFieldLabel           matlab.ui.control.Label
        GenderButtonGroup               matlab.ui.container.ButtonGroup
        FemaleButton                    matlab.ui.control.RadioButton
        MaleButton                      matlab.ui.control.RadioButton
        GainLossEditField               matlab.ui.control.EditField
        RecommendedWeightGainLossEditField  matlab.ui.control.NumericEditField
        RecommendedWeightGainLossEditFieldLabel  matlab.ui.control.Label
        RecommendedFoodTextArea         matlab.ui.control.TextArea
        RecommendedFoodTextAreaLabel    matlab.ui.control.Label
        SpecificInterpretationTextArea  matlab.ui.control.TextArea
        SpecificInterpretationTextAreaLabel  matlab.ui.control.Label
        HeightLabel                     matlab.ui.control.Label
        inEditField                     matlab.ui.control.NumericEditField
        inEditFieldLabel                matlab.ui.control.Label
        BMIEditField                    matlab.ui.control.NumericEditField
        BMIEditFieldLabel               matlab.ui.control.Label
        ftEditField                     matlab.ui.control.NumericEditField
        ftEditFieldLabel                matlab.ui.control.Label
        WeightkgEditField               matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel          matlab.ui.control.Label
        InterpretationEditField         matlab.ui.control.EditField
        InterpretationEditFieldLabel    matlab.ui.control.Label
        CheckFoodHealthButton           matlab.ui.control.Button
        FOODHEALTHCALCULATORLabel       matlab.ui.control.Label
        PleaseenteryourweightandheighthereLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CheckFoodHealthButton
        function CheckFoodHealthButtonPushed(app, event)
            weight = app.WeightkgEditField.Value;
            heightft = app.ftEditField.Value;
            heightin = app.inEditField.Value;
            height = (heightft*0.3048) + (heightin*0.0254);
            age = app.AgeEditField.Value;
            
            if (app.MaleButton.Value == 1)
                gender_value = 0;
            end
            
            if (app.FemaleButton.Value == 1)
                gender_value = 1;
            end

            BMI = weight/(height)^2;
            
            Body_Fat = -44.988 + (0.503*age) + (10.689*gender_value) + (3.172*BMI) - (0.026 * BMI * BMI) + (0.181*BMI*gender_value)-(0.02*BMI*age)-(0.005*BMI*BMI*gender_value) + (0.00021*BMI*BMI*age);

            app.BMIEditField.Value = BMI;
            app.BodyFatEditField.Value = Body_Fat;

            if(BMI < 18.5)
                app.InterpretationEditField.Value = 'UNDERWEIGHT';
                app.SpecificInterpretationTextArea.Value = 'It seems you are underweight. You need to increase your food intake. Below is the list of appropriate foods in order to increase your BMI.';
                app.RecommendedFoodTextArea.Value = 'Whole-wheat breads, dairy, lean meat, pastas';
                app.RecommendedWeightGainLossEditField.Value = (19*(height)^2)-weight;
                app.GainLossEditField.Value = 'GAIN';

            end
            if(BMI >= 18.5 && BMI < 25)
                app.InterpretationEditField.Value = 'NORMAL WEIGHT';
                app.SpecificInterpretationTextArea.Value = 'Congrats! You have a normal BMI. Try your best to maintain this.';
                app.RecommendedFoodTextArea.Value = 'Maintain your diet--keep yourself healthy.';
                app.GainLossEditField.Value = 'MAINTAIN';
                app.RecommendedWeightGainLossEditField.Value = 0;
            end
            if(BMI >= 25 && BMI < 30)
                app.InterpretationEditField.Value = 'OVERWEIGHT';
                app.SpecificInterpretationTextArea.Value = 'It seems you have exceeded the recommended BMI. Try cutting down on your food intake and exercise.';
                app.RecommendedFoodTextArea.Value = 'Whole-grain food, Vegetables, Fruits, Fish, Nuts, Poultry';
                app.GainLossEditField.Value = 'LOSE';
                app.RecommendedWeightGainLossEditField.Value = (23*(height)^2)-weight;
            end
            if(BMI >= 30)
                app.InterpretationEditField.Value = 'OBESITY';
                app.SpecificInterpretationTextArea.Value = 'You have exceeded way beyond what is the recommended BMI. Please consult a doctor.';
                app.RecommendedFoodTextArea.Value = 'Whole-wheat breads, dairy, lean meat, pastas';
                app.GainLossEditField.Value = 'ATTN REQD';
                app.RecommendedWeightGainLossEditField.Value = (23*(height)^2)-weight;
            end

            app.BMIGauge.Value = BMI;
        end

        % Button pushed function: CheckCaloriesButton
        function CheckCaloriesButtonPushed(app, event)
            totalcal = 0;
           
            if (app.Food1DropDown.Value == "Eggs")
                totalcal = totalcal + 225;
            end
            if (app.Food1DropDown.Value == "Vegetables")
                totalcal = totalcal + 124;
            end
            if (app.Food1DropDown.Value == "Rice")
                totalcal = totalcal + 282;
            end
            if (app.Food1DropDown.Value == "Fruits")
               totalcal = totalcal +82;
            end
            if (app.Food1DropDown.Value == "Bread")
                totalcal = totalcal +70;
            end
            if (app.Food1DropDown.Value == "Meat")
                totalcal = totalcal +864;
            end
            if (app.Food1DropDown.Value == "Fish")
                totalcal = totalcal +143;
            end
            if (app.Food1DropDown.Value == "Dairy")
                totalcal = totalcal +316;
            end

             
            if (app.Food2DropDown.Value == "Eggs")
                totalcal = totalcal + 225;
            end
            if (app.Food2DropDown.Value == "Vegetables")
                totalcal = totalcal + 124;
            end
            if (app.Food2DropDown.Value == "Rice")
                totalcal = totalcal + 282;
            end
            if (app.Food2DropDown.Value == "Fruits")
               totalcal = totalcal +82;
            end
            if (app.Food2DropDown.Value == "Bread")
                totalcal = totalcal +70;
            end
            if (app.Food2DropDown.Value == "Meat")
                totalcal = totalcal +864;
            end
            if (app.Food2DropDown.Value == "Fish")
                totalcal = totalcal +143;
            end
            if (app.Food2DropDown.Value == "Dairy")
                totalcal = totalcal +316;
            end
       
            if (app.Food3DropDown.Value == "Eggs")
                totalcal = totalcal + 225;
            end
            if (app.Food3DropDown.Value == "Vegetables")
                totalcal = totalcal + 124;
            end
            if (app.Food3DropDown.Value == "Rice")
                totalcal = totalcal + 282;
            end
            if (app.Food3DropDown.Value == "Fruits")
               totalcal = totalcal +82;
            end
            if (app.Food3DropDown.Value == "Bread")
                totalcal = totalcal +70;
            end
            if (app.Food3DropDown.Value == "Meat")
                totalcal = totalcal +864;
            end
            if (app.Food3DropDown.Value == "Fish")
                totalcal = totalcal +143;
            end
            if (app.Food3DropDown.Value == "Dairy")
                totalcal = totalcal +316;
            end

            if (app.Food4DropDown.Value == "Eggs")
                totalcal = totalcal + 225;
            end
            if (app.Food4DropDown.Value == "Vegetables")
                totalcal = totalcal + 124;
            end
            if (app.Food4DropDown.Value == "Rice")
                totalcal = totalcal + 282;
            end
            if (app.Food4DropDown.Value == "Fruits")
               totalcal = totalcal +82;
            end
            if (app.Food4DropDown.Value == "Bread")
                totalcal = totalcal +70;
            end
            if (app.Food4DropDown.Value == "Meat")
                totalcal = totalcal +864;
            end
            if (app.Food4DropDown.Value == "Fish")
                totalcal = totalcal +143;
            end
            if (app.Food4DropDown.Value == "Dairy")
                totalcal = totalcal +316;
            end
    
            if (app.Food5DropDown.Value == "Eggs")
                totalcal = totalcal + 225;
            end
            if (app.Food5DropDown.Value == "Vegetables")
                totalcal = totalcal + 124;
            end
            if (app.Food5DropDown.Value == "Rice")
                totalcal = totalcal + 282;
            end
            if (app.Food5DropDown.Value == "Fruits")
               totalcal = totalcal +82;
            end
            if (app.Food5DropDown.Value == "Bread")
                totalcal = totalcal +70;
            end
            if (app.Food5DropDown.Value == "Meat")
                totalcal = totalcal +864;
            end
            if (app.Food5DropDown.Value == "Fish")
                totalcal = totalcal +143;
            end
            if (app.Food5DropDown.Value == "Dairy")
                totalcal = totalcal +316;
            end

            if (app.Food6DropDown.Value == "Eggs")
                totalcal = totalcal + 225;
            end
            if (app.Food6DropDown.Value == "Vegetables")
                totalcal = totalcal + 124;
            end
            if (app.Food6DropDown.Value == "Rice")
                totalcal = totalcal + 282;
            end
            if (app.Food6DropDown.Value == "Fruits")
               totalcal = totalcal +82;
            end
            if (app.Food6DropDown.Value == "Bread")
                totalcal = totalcal +70;
            end
            if (app.Food6DropDown.Value == "Meat")
                totalcal = totalcal +864;
            end
            if (app.Food6DropDown.Value == "Fish")
                totalcal = totalcal +143;
            end
            if (app.Food6DropDown.Value == "Dairy")
                totalcal = totalcal +316;
            end

            if (app.Food7DropDown.Value == "Eggs")
                totalcal = totalcal + 225;
            end
            if (app.Food7DropDown.Value == "Vegetables")
                totalcal = totalcal + 124;
            end
            if (app.Food7DropDown.Value == "Rice")
                totalcal = totalcal + 282;
            end
            if (app.Food7DropDown.Value == "Fruits")
               totalcal = totalcal +82;
            end
            if (app.Food7DropDown.Value == "Bread")
                totalcal = totalcal +70;
            end
            if (app.Food7DropDown.Value == "Meat")
                totalcal = totalcal +864;
            end
            if (app.Food7DropDown.Value == "Fish")
                totalcal = totalcal +143;
            end
            if (app.Food7DropDown.Value == "Dairy")
                totalcal = totalcal +316;
            end

            if (app.Food8DropDown.Value == "Eggs")
                totalcal = totalcal + 225;
            end
            if (app.Food8DropDown.Value == "Vegetables")
                totalcal = totalcal + 124;
            end
            if (app.Food8DropDown.Value == "Rice")
                totalcal = totalcal + 282;
            end
            if (app.Food8DropDown.Value == "Fruits")
               totalcal = totalcal +82;
            end
            if (app.Food8DropDown.Value == "Bread")
                totalcal = totalcal +70;
            end
            if (app.Food8DropDown.Value == "Meat")
                totalcal = totalcal +864;
            end
            if (app.Food8DropDown.Value == "Fish")
                totalcal = totalcal +143;
            end
            if (app.Food8DropDown.Value == "Dairy")
                totalcal = totalcal +316;
            end

            if (app.Food9DropDown.Value == "Eggs")
                totalcal = totalcal + 225;
            end
            if (app.Food9DropDown.Value == "Vegetables")
                totalcal = totalcal + 124;
            end
            if (app.Food9DropDown.Value == "Rice")
                totalcal = totalcal + 282;
            end
            if (app.Food9DropDown.Value == "Fruits")
               totalcal = totalcal +82;
            end
            if (app.Food9DropDown.Value == "Bread")
                totalcal = totalcal +70;
            end
            if (app.Food9DropDown.Value == "Meat")
                totalcal = totalcal +864;
            end
            if (app.Food9DropDown.Value == "Fish")
                totalcal = totalcal +143;
            end
            if (app.Food9DropDown.Value == "Dairy")
                totalcal = totalcal +316;
            end
            app.TotalCalorieskcalEditField.Value = totalcal;
            app.CCountGauge.Value = totalcal/1200;
            app.RatiovsAverageCalorieIntakeGauge.Value = totalcal/2000;

           if(app.TotalCalorieskcalEditField.Value > 2500)
               app.CalorieInterpretationEditField.Value = 'OVER';
               app.CalorieaddEditField.Value = 2500 - totalcal;
               xc = totalcal - 2500;
               app.CAreaTextArea.Value = sprintf("You exceeded the recommended daily calorie count by %d calories. Consider cutting down on your diet.",xc);
           end
           if(app.TotalCalorieskcalEditField.Value < 1200)
               app.CalorieInterpretationEditField.Value = 'LACK';
               app.CalorieaddEditField.Value = 1200 - totalcal;
               xc = 1200 - totalcal;
               app.CAreaTextArea.Value = sprintf("You aren't getting enough calories. You are short of %d calories from the minimum recommended daily intake. Consider either eating more or changing your diet to include calorie-rich foods.",xc);
           end
           if(app.TotalCalorieskcalEditField.Value >= 1200 && app.TotalCalorieskcalEditField.Value <= 2500)
               app.CalorieInterpretationEditField.Value = 'GOOD';
               app.CalorieaddEditField.Value = 0;
               app.CAreaTextArea.Value = "You have just the right amount of daily calorie intake. Remember to maintain this and a healthy diet with exercise at all times.";
           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PleaseenteryourweightandheighthereLabel = uilabel(app.UIFigure);
            app.PleaseenteryourweightandheighthereLabel.Text = 'Please enter your weight and height here:';
            app.FOODHEALTHCALCULATORLabel = uilabel(app.UIFigure);
            app.FOODHEALTHCALCULATORLabel.Text = 'FOOD HEALTH CALCULATOR';
            app.CheckFoodHealthButton = uibutton(app.UIFigure, 'push');
            app.CheckFoodHealthButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFoodHealthButtonPushed, true);
            app.CheckFoodHealthButton.Text = 'Check Food Health';
            app.InterpretationEditFieldLabel = uilabel(app.UIFigure);
            app.InterpretationEditFieldLabel.Text = 'Interpretation';
            app.InterpretationEditField = uieditfield(app.UIFigure, 'text');
            app.WeightkgEditFieldLabel = uilabel(app.UIFigure);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.ftEditFieldLabel = uilabel(app.UIFigure);
            app.ftEditFieldLabel.Text = 'ft';
            app.ftEditField = uieditfield(app.UIFigure, 'numeric');
            app.BMIEditFieldLabel = uilabel(app.UIFigure);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.UIFigure, 'numeric');
            app.inEditFieldLabel = uilabel(app.UIFigure);
            app.inEditFieldLabel.Text = 'in';
            app.inEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightLabel = uilabel(app.UIFigure);
            app.HeightLabel.Text = 'Height';
            app.SpecificInterpretationTextAreaLabel = uilabel(app.UIFigure);
            app.SpecificInterpretationTextAreaLabel.Text = 'Specific Interpretation';
            app.SpecificInterpretationTextArea = uitextarea(app.UIFigure);
            app.RecommendedFoodTextAreaLabel = uilabel(app.UIFigure);
            app.RecommendedFoodTextAreaLabel.Text = 'Recommended Food';
            app.RecommendedFoodTextArea = uitextarea(app.UIFigure);
            app.RecommendedWeightGainLossEditFieldLabel = uilabel(app.UIFigure);
            app.RecommendedWeightGainLossEditFieldLabel.Text = 'Recommended Weight Gain/Loss';
            app.RecommendedWeightGainLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainLossEditField = uieditfield(app.UIFigure, 'text');
            app.GenderButtonGroup = uibuttongroup(app.UIFigure);
            app.GenderButtonGroup.Title = 'Gender';
            app.MaleButton = uiradiobutton(app.GenderButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.GenderButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.BodyFatEditFieldLabel = uilabel(app.UIFigure);
            app.BodyFatEditFieldLabel.Text = 'Body Fat %';
            app.BodyFatEditField = uieditfield(app.UIFigure, 'numeric');
            app.BodyFatEditField.ValueDisplayFormat = '%.0f';
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CheckCaloriesButton = uibutton(app.UIFigure, 'push');
            app.CheckCaloriesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckCaloriesButtonPushed, true);
            app.CheckCaloriesButton.Text = 'Check Calories';
            app.WhatdoesyourdailydietcomposeofLabel = uilabel(app.UIFigure);
            app.WhatdoesyourdailydietcomposeofLabel.Text = 'What does your daily diet compose of?';
            app.BreakfastLabel = uilabel(app.UIFigure);
            app.BreakfastLabel.Text = 'Breakfast';
            app.LunchLabel = uilabel(app.UIFigure);
            app.LunchLabel.Text = 'Lunch';
            app.DinnerLabel = uilabel(app.UIFigure);
            app.DinnerLabel.Text = 'Dinner';
            app.Food1DropDownLabel = uilabel(app.UIFigure);
            app.Food1DropDownLabel.Text = 'Food 1';
            app.Food1DropDown = uidropdown(app.UIFigure);
            app.Food1DropDown.Items = {'None', 'Vegetables', 'Rice', 'Fruits', 'Bread', 'Meat', 'Fish', 'Dairy', 'Eggs'};
            app.Food1DropDown.Value = 'None';
            app.Food2DropDownLabel = uilabel(app.UIFigure);
            app.Food2DropDownLabel.Text = 'Food 2';
            app.Food2DropDown = uidropdown(app.UIFigure);
            app.Food2DropDown.Items = {'None', 'Vegetables', 'Rice', 'Fruits', 'Bread', 'Meat', 'Fish', 'Dairy', 'Eggs'};
            app.Food2DropDown.Value = 'None';
            app.Food3DropDownLabel = uilabel(app.UIFigure);
            app.Food3DropDownLabel.Text = 'Food 3';
            app.Food3DropDown = uidropdown(app.UIFigure);
            app.Food3DropDown.Items = {'None', 'Vegetables', 'Rice', 'Fruits', 'Bread', 'Meat', 'Fish', 'Dairy', 'Eggs'};
            app.Food3DropDown.Value = 'None';
            app.Food4DropDownLabel = uilabel(app.UIFigure);
            app.Food4DropDownLabel.Text = 'Food 4';
            app.Food4DropDown = uidropdown(app.UIFigure);
            app.Food4DropDown.Items = {'None', 'Vegetables', 'Rice', 'Fruits', 'Bread', 'Meat', 'Fish', 'Dairy', 'Eggs'};
            app.Food4DropDown.Value = 'None';
            app.Food5DropDownLabel = uilabel(app.UIFigure);
            app.Food5DropDownLabel.Text = 'Food 5';
            app.Food5DropDown = uidropdown(app.UIFigure);
            app.Food5DropDown.Items = {'None', 'Vegetables', 'Rice', 'Fruits', 'Bread', 'Meat', 'Fish', 'Dairy', 'Eggs'};
            app.Food5DropDown.Value = 'None';
            app.Food6DropDownLabel = uilabel(app.UIFigure);
            app.Food6DropDownLabel.Text = 'Food 6';
            app.Food6DropDown = uidropdown(app.UIFigure);
            app.Food6DropDown.Items = {'None', 'Vegetables', 'Rice', 'Fruits', 'Bread', 'Meat', 'Fish', 'Dairy', 'Eggs'};
            app.Food6DropDown.Value = 'None';
            app.Food7DropDownLabel = uilabel(app.UIFigure);
            app.Food7DropDownLabel.Text = 'Food 7';
            app.Food7DropDown = uidropdown(app.UIFigure);
            app.Food7DropDown.Items = {'None', 'Vegetables', 'Rice', 'Fruits', 'Bread', 'Meat', 'Fish', 'Dairy', 'Eggs'};
            app.Food7DropDown.Value = 'None';
            app.Food8DropDownLabel = uilabel(app.UIFigure);
            app.Food8DropDownLabel.Text = 'Food 8';
            app.Food8DropDown = uidropdown(app.UIFigure);
            app.Food8DropDown.Items = {'None', 'Vegetables', 'Rice', 'Fruits', 'Bread', 'Meat', 'Fish', 'Dairy', 'Eggs'};
            app.Food8DropDown.Value = 'None';
            app.Food9DropDownLabel = uilabel(app.UIFigure);
            app.Food9DropDownLabel.Text = 'Food 9';
            app.Food9DropDown = uidropdown(app.UIFigure);
            app.Food9DropDown.Items = {'None', 'Vegetables', 'Rice', 'Fruits', 'Bread', 'Meat', 'Fish', 'Dairy', 'Eggs'};
            app.Food9DropDown.Value = 'None';
            app.DailyCalorieIntakeforanadultisaround2000caloriesLabel = uilabel(app.UIFigure);
            app.DailyCalorieIntakeforanadultisaround2000caloriesLabel.Text = '*Daily Calorie Intake for an adult is around 2000 calories.';
            app.CalorieInterpretationEditFieldLabel = uilabel(app.UIFigure);
            app.CalorieInterpretationEditFieldLabel.Text = 'Calorie Interpretation';
            app.CalorieInterpretationEditField = uieditfield(app.UIFigure, 'text');
            app.CalorieaddEditField = uieditfield(app.UIFigure, 'numeric');
            app.CCountGauge = uigauge(app.UIFigure, 'linear');
            app.CCountGauge.Limits = [0 5];
            app.CCountGauge.MajorTicks = [0 1 2 3 4 5];
            app.CAreaTextArea = uitextarea(app.UIFigure);
            app.TotalCalorieskcalEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCalorieskcalEditFieldLabel.Text = 'Total Calories (kcal)';
            app.TotalCalorieskcalEditField = uieditfield(app.UIFigure, 'numeric');
            app.RatiovsMinimumCalorieIntakeLabel = uilabel(app.UIFigure);
            app.RatiovsMinimumCalorieIntakeLabel.Text = 'Ratio vs Minimum Calorie Intake';
            app.RatiovsAverageCalorieIntakeGaugeLabel = uilabel(app.UIFigure);
            app.RatiovsAverageCalorieIntakeGaugeLabel.Text = 'Ratio vs Average Calorie Intake';
            app.RatiovsAverageCalorieIntakeGauge = uigauge(app.UIFigure, 'linear');
            app.RatiovsAverageCalorieIntakeGauge.Limits = [0 5];
            app.RatiovsAverageCalorieIntakeGauge.MajorTicks = [0 1 2 3 4 5];
            app.BMIGauge = uigauge(app.UIFigure, 'semicircular');
            app.BMIGauge.Limits = [0 50];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fproj_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end